classdef LAB_Inversa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TA3                     matlab.ui.Figure
        Zpos_2                  matlab.ui.control.NumericEditField
        ZEditFieldLabel_2       matlab.ui.control.Label
        Xpos_2                  matlab.ui.control.NumericEditField
        Label_4                 matlab.ui.control.Label
        Xpos_4                  matlab.ui.control.EditField
        GripperLabel            matlab.ui.control.Label
        RECOGERLIBROSButton     matlab.ui.control.Button
        YawEditField_4          matlab.ui.control.NumericEditField
        YawEditField_4Label     matlab.ui.control.Label
        PitchEditField_4        matlab.ui.control.NumericEditField
        PitchEditField_4Label   matlab.ui.control.Label
        RollEditField_4         matlab.ui.control.NumericEditField
        RollEditField_4Label    matlab.ui.control.Label
        YawEditField_3          matlab.ui.control.NumericEditField
        YawEditField_3Label     matlab.ui.control.Label
        PitchEditField_3        matlab.ui.control.NumericEditField
        PitchEditField_3Label   matlab.ui.control.Label
        RollEditField_3         matlab.ui.control.NumericEditField
        RollEditField_3Label    matlab.ui.control.Label
        YawEditField_2          matlab.ui.control.NumericEditField
        YawEditField_2Label     matlab.ui.control.Label
        PitchEditField_2        matlab.ui.control.NumericEditField
        PitchEditField_2Label   matlab.ui.control.Label
        RollEditField_2         matlab.ui.control.NumericEditField
        RollEditField_2Label    matlab.ui.control.Label
        YawEditField            matlab.ui.control.NumericEditField
        YawEditFieldLabel       matlab.ui.control.Label
        PitchEditField          matlab.ui.control.NumericEditField
        PitchEditFieldLabel     matlab.ui.control.Label
        RollEditField           matlab.ui.control.NumericEditField
        RollEditFieldLabel      matlab.ui.control.Label
        MTH03Label              matlab.ui.control.Label
        MTH23Label              matlab.ui.control.Label
        MTH12Label              matlab.ui.control.Label
        MTH01Label              matlab.ui.control.Label
        MTH03                   matlab.ui.control.TextArea
        MTH23                   matlab.ui.control.TextArea
        MTH12                   matlab.ui.control.TextArea
        MTH01                   matlab.ui.control.TextArea
        CodoAbajoCheckBox       matlab.ui.control.CheckBox
        CodoArribaCheckBox      matlab.ui.control.CheckBox
        Zpos_3                  matlab.ui.control.NumericEditField
        ZEditField_2Label       matlab.ui.control.Label
        XEditFieldLabel_2       matlab.ui.control.Label
        Ypos_3                  matlab.ui.control.NumericEditField
        YEditField_2Label       matlab.ui.control.Label
        Ypos_2                  matlab.ui.control.NumericEditField
        YEditFieldLabel_2       matlab.ui.control.Label
        Xpos_3                  matlab.ui.control.NumericEditField
        XEditFieldLabel_3       matlab.ui.control.Label
        ActualPosLabel          matlab.ui.control.Label
        cmLabel_9               matlab.ui.control.Label
        cmLabel_8               matlab.ui.control.Label
        cmLabel_7               matlab.ui.control.Label
        INVERSAButton           matlab.ui.control.Button
        cmLabel_6               matlab.ui.control.Label
        cmLabel_5               matlab.ui.control.Label
        cmLabel_4               matlab.ui.control.Label
        PosEndLabel             matlab.ui.control.Label
        Theta2num               matlab.ui.control.EditField
        Theta2EditFieldLabel    matlab.ui.control.Label
        Theta1num               matlab.ui.control.EditField
        Theta1EditFieldLabel    matlab.ui.control.Label
        Zpos                    matlab.ui.control.EditField
        Label_3                 matlab.ui.control.Label
        ZEditFieldLabel         matlab.ui.control.Label
        Ypos                    matlab.ui.control.EditField
        Label                   matlab.ui.control.Label
        Label_2                 matlab.ui.control.Label
        YEditFieldLabel         matlab.ui.control.Label
        Xpos                    matlab.ui.control.EditField
        XEditFieldLabel         matlab.ui.control.Label
        Theta2EditFieldLabel_2  matlab.ui.control.Label
        Theta3num               matlab.ui.control.EditField
        cmLabel_3               matlab.ui.control.Label
        cmLabel_2               matlab.ui.control.Label
        cmLabel                 matlab.ui.control.Label
        AngleLabel              matlab.ui.control.Label
        PosStartLabel           matlab.ui.control.Label
        DIBU_2                  matlab.ui.control.UIAxes
        Aceleracion             matlab.ui.control.UIAxes
        Velocidad               matlab.ui.control.UIAxes
        Posicion                matlab.ui.control.UIAxes
        DIBU                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        s;
        q11;
        q22;
        q33;
        q44;
        q55;
        q66;
        q77;
        q88;
    end
 
    methods (Access = private)

        function RUTALINEAL(app,P1X,P1Y,P1Z,P2X,P2Y,P2Z)
            l1=92.5;
            l2=171;
            l3=236.5;

            %CINEMATICA INVERSA
            %PUNTO 1
            P1x = P1X;
            P1y = P1Y;
            P1z = P1Z;

            %PUNTO 2
            P2x = P2X;
            P2y = P2Y;
            P2z = P2Z;

            n = 15; %Numero de puntos entre coordenadas
            x = 1:1:n;

            Px_P1toP2 = linspace(P1x,P2x,n);
            Py_P1toP2 = linspace(P1y,P2y,n);
            Pz_P1toP2 = linspace(P1z,P2z,n);

            for i = 1:n
                %Cinematica Inversa
                [theta1(i),theta2(i),theta3(i)] = InverseKinematics3R(app,l1,l2,l3,Px_P1toP2(i),Py_P1toP2(i),Pz_P1toP2(i));

                q1=rad2deg(theta1(i));
                q2=rad2deg(theta2(i));
                q3=rad2deg(theta3(i));

                trama=[num2str(q1) ',' num2str(q2) ',' num2str(q3)];
                fprintf(app.s,'%s\n',trama,'sync');

                while true
                    sala = ENVIO(app);
                    if sala==1
                        sala=0;
                        break;
                    end
                end

                app.Xpos.Value = num2str(Px_P1toP2(i));
                app.Ypos.Value = num2str(Py_P1toP2(i));
                app.Zpos.Value = num2str(Pz_P1toP2(i));

                app.Theta1num.Value = num2str(rad2deg(theta1(i)));
                app.Theta2num.Value = num2str(rad2deg(theta2(i)));
                app.Theta3num.Value = num2str(rad2deg(theta3(i)));

                %Cinematica Directa
                [MTH] = ForwardKinematics3R(app,l1,l2,l3,theta1(i),theta2(i),theta3(i));
                hold on;
                plot3(MTH.t(1),MTH.t(2),MTH.t(3),'.r')
            end
        end

        function RUTACIRCULAR(app,P1X,P1Y,P1Z,P2X,P2Y,P2Z)

            l1=92.5;
            l2=171;
            l3=236.5;

            %CINEMATICA INVERSA
            P1x = P1X;
            P1y = P1Y;
            P1z = P1Z;

            [theta1_P1,theta2_P1,theta3_P1] = InverseKinematics3R(app,l1,l2,l3,P1x,P1y,P1z);

            %PUNTO 2
            P2x = P2X;
            P2y = P2Y;
            P2z = P2Z;


            [theta1_P2,theta2_P2,theta3_P2] = InverseKinematics3R(app,l1,l2,l3,P2x,P2y,P2z);

            n = 15;  %Numero de puntos entre coordenadas
            x = 1:1:n;

            theta1_P1toP2 = linspace(theta1_P1,theta1_P2,n);
            theta2_P1toP2 = linspace(theta2_P1,theta2_P2,n);
            theta3_P1toP2 = linspace(theta3_P1,theta3_P2,n);

            for i=1:n
                %Cinematica Directa Peter Corke
                [MTH] = ForwardKinematics3R(app,l1,l2,l3,theta1_P1toP2(i),theta2_P1toP2(i),theta3_P1toP2(i));
                d(:,i) = MTH.t;%.R agarra la matriz 3*3 de rotacion

                q1=rad2deg(theta1_P1toP2(i));
                q2=rad2deg(theta2_P1toP2(i));
                q3=rad2deg(theta3_P1toP2(i));

                trama=[num2str(q1) ',' num2str(q2) ',' num2str(q3)];
                fprintf(app.s,'%s\n',trama,'sync');
                while true
                    sala = ENVIO(app);
                    if sala==1
                        sala=0;
                        break;
                    end
                end

                app.Xpos.Value = num2str(d(1,i));
                app.Ypos.Value = num2str(d(2,i));
                app.Zpos.Value = num2str(d(3,i));

                app.Theta1num.Value = num2str(rad2deg(theta1_P1toP2(i)));
                app.Theta2num.Value = num2str(rad2deg(theta2_P1toP2(i)));
                app.Theta3num.Value = num2str(rad2deg(theta3_P1toP2(i)));
                hold on;
                plot3(d(1,i),d(2,i),d(3,i),'.b')
            end
        end
        
        function [theta1,theta2,theta3,theta4] = InverseKinematics3R(~,l1,l2,l3,Px,Py,Pz,C,G)
            PX=Px;
            PY=Py;
            PZ=Pz;

            e = sqrt(PX^2 + PY^2);
            c = PZ - l1;
            b = sqrt(e^2 + c^2);
            % Theta 1
            theta1 = atan2(PY,PX);
            % Theta 3
            cos_theta3 = (b^2-l2^2-l3^2)/(2*l2*l3);
            if C == 1
                sen_theta3 = sqrt(1-(cos_theta3)^2);
            else
                sen_theta3 = -sqrt(1-(cos_theta3)^2);
            end
            theta3 = atan2(sen_theta3,cos_theta3);
            if theta3 <= -pi
                theta3 = (2*pi)+theta3;
            end
            % Theta 2
            alpha = atan2(c,e);
            phi = atan2(l3*sen_theta3, l2+l3*cos_theta3);
            theta2 = alpha - phi;
            if theta2 <= -pi
                theta2 = (2*pi)+theta2;
            end
            % Gripper
            if G == 0
                theta4 = 0*(pi/180); % Cerrado
            else
                theta4 = 45 * (pi/180); % Abierto
            end
        end

        function [MTH] = ForwardKinematics3R(app,l1,l2,l3,q1,q2,q3)
            %MATRICES DH
            %T01
            TZ1=[1 0 0 0;
                 0 1 0 0;
                 0 0 1 l1;
                 0 0 0 1];
            RZ1=[cos(q1) -sin(q1) 0 0;
                 sin(q1) cos(q1)  0 0;
                 0 0 1 0;
                 0 0 0 1];
            TX1=[1 0 0 0;
                 0 1 0 0;
                 0 0 1 0;
                 0 0 0 1];
            RX1=[1 0 0 0;
                 0 cos(pi/2) -sin(pi/2) 0
                 0 sin(pi/2)  cos(pi/2) 0
                 0 0 0 1];
            T01 = RZ1*TZ1*TX1*RX1;

            textoMatriz01 = sprintf('[%.1f %.1f %.1f %.1f;\n %.1f %.1f %.1f %.1f;\n %.1f %.1f %.1f %.1f;\n %.1f %.1f %.1f %.1f]', T01');
            app.MTH01.Value = textoMatriz01;

            m1 = T01(1:3,1:3);
            r1 = rad2deg(tr2rpy(m1,'zyx'));
            app.RollEditField.Value = r1(1);
            app.PitchEditField.Value = r1(2);
            app.YawEditField.Value = r1(3);

            %T12
            TZ2=[1 0 0 0;
                 0 1 0 0;
                 0 0 1 0;
                 0 0 0 1];
            RZ2=[cos(q2) -sin(q2) 0 0;
                 sin(q2) cos(q2)  0 0;
                 0 0 1 0;
                 0 0 0 1];
            TX2=[1 0 0 l2;
                 0 1 0 0;
                 0 0 1 0;
                 0 0 0 1];
            RX2=[1 0 0 0;
                 0 cos(0) -sin(0) 0
                 0 sin(0)  cos(0) 0
                 0 0 0 1];
            T12 = RZ2*TZ2*TX2*RX2;

            textoMatriz12 = sprintf('[%.1f %.1f %.1f %.1f;\n %.1f %.1f %.1f %.1f;\n %.1f %.1f %.1f %.1f;\n %.1f %.1f %.1f %.1f]', T12');
            app.MTH12.Value = textoMatriz12;

            m2 = T12(1:3,1:3);
            r2 = rad2deg(tr2rpy(m2,'zyx'));
            app.RollEditField_2.Value = r2(1);
            app.PitchEditField_2.Value = r2(2);
            app.YawEditField_2.Value = r2(3);


            %T23
            TZ3=[1 0 0 0;
                 0 1 0 0;
                 0 0 1 0;
                 0 0 0 1];
            RZ3=[cos(q3) -sin(q3)  0 0;
                 sin(q3)  cos(q3)  0 0;
                 0 0 1 0;
                 0 0 0 1];
            TX3=[1 0 0 l3;
                 0 1 0 0;
                 0 0 1 0;
                 0 0 0 1];
            RX3=[1 0 0 0;
                 0 cos(0) -sin(0) 0
                 0 sin(0)  cos(0) 0
                 0 0 0 1];
            T23 = RZ3*TZ3*TX3*RX3;

            textoMatriz23 = sprintf('[%.1f %.1f %.1f %.1f;\n %.1f %.1f %.1f %.1f;\n %.1f %.1f %.1f %.1f;\n %.1f %.1f %.1f %.1f]', T23');
            app.MTH23.Value = textoMatriz23;

            m3 = T23(1:3,1:3);
            r3 = rad2deg(tr2rpy(m3,'zyx'));
            app.RollEditField_3.Value = r3(1);
            app.PitchEditField_3.Value = r3(2);
            app.YawEditField_3.Value = r3(3);


            T03 = T01*T12*T23;

            textoMatriz03 = sprintf('[%.1f %.1f %.1f %.1f;\n %.1f %.1f %.1f %.1f;\n %.1f %.1f %.1f %.1f;\n %.1f %.1f %.1f %.1f]', T03');
            app.MTH03.Value = textoMatriz03;

            m4 = T03(1:3,1:3);
            r4 = rad2deg(tr2rpy(m4,'zyx'));
            app.RollEditField_4.Value = r4(1);
            app.PitchEditField_4.Value = r4(2);
            app.YawEditField_4.Value = r4(3);


            % Robot Peter Corke

            R(1) = Link('revolute','d',l1,'alpha',pi/2,'a',0,'offset',0);
            R(2) = Link('revolute','d',0,'alpha',0,'a',l2,'offset',0);
            R(3) = Link('revolute','d',0,'alpha',0,'a',l3,'offset',0);

            Robot = SerialLink(R,'name','Bender');
            MTH = Robot.fkine([q1,q2,q3]);
            %[x1] = T03(3,1)
            %[y1] = T03(3,2)
            %[z1] = T03(3,3)
            axes(app.DIBU);
            Robot.plot([q1,q2,q3],'scale',1.0,'workspace',[-500 500 -500 500 -500 500])
            %scatter3(x1,y1,z1);

            % Modelo CAD

            cd STLNuevo\
            p0 = stlread('base2Ejes.stl');
            p1 = stlread('brazoAEjes.stl');
            p2 = stlread('brazoBEjes1.stl');
            p3 = stlread('base1Ejes.stl');
            cd('..');

            axes(app.DIBU_2);
            cla;
            xlabel('Eje X (r)');
            ylabel('Eje Y (g)');
            zlabel('Eje Z (y)');
            view(3);
            axis equal;
            grid on;
            hold on;
            light('Position', [0, 0, 1]); 
            material('shiny');

            % MTH de los ejes
            [Eje1,Eje2,Eje3]= RPEjes(app,q1,q2,q3,l1,l2,l3);

            % Rotar Base
            R0=Rotacion(app,0,0,-q1);
            p0Points=(R0 * p0.Points')';

            % Rotar Eslabon 1
            % Inclinar
            R1=Rotacion(app,0,q2,0);
            p1Points=(R1 * p1.Points')';
            % Rotar
            R1=Rotacion(app,0,0,-q1);
            p1Points=(R1 * p1Points')';
            p1Points(:,3)=p1Points(:,3) + Eje1(3,4);

            % Rotar Eslabon2
            % Inclinar
            R2=Rotacion(app,0,q3+q2,0);
            p2Points=(R2 * p2.Points')';
            % Rotar
            R2=Rotacion(app,0,0,-q1);
            p2Points=(R2 * p2Points')';
            p2Points(:,1)=p2Points(:,1)+Eje2(1,4);%X
            p2Points(:,2)=p2Points(:,2)+Eje2(2,4);%Y
            p2Points(:,3)=p2Points(:,3)+Eje2(3,4);%Z

            % BaseEstatica
            R3=Rotacion(app,0,0,0);
            p3Points=(R3 * p3.Points')';

            % Unir Robot
            h0=patch('Vertices', p0Points, 'Faces', p0.ConnectivityList, 'FaceColor', 'black', 'EdgeColor', 'none');
            h1=patch('Vertices', p1Points, 'Faces', p1.ConnectivityList, 'FaceColor', 'black', 'EdgeColor', 'none');
            h2=patch('Vertices', p2Points, 'Faces', p2.ConnectivityList, 'FaceColor', 'black', 'EdgeColor', 'none');
            h3=patch('Vertices', p3Points, 'Faces', p3.ConnectivityList, 'FaceColor', 'black', 'EdgeColor', 'none');

            % Dibujar Ejes
            sc=50;
            Eje0=[cos(q1) -sin(q1) 0 ;
                  sin(q1) cos(q1)  0 ;
                     0       0     1 ];
            Eje0xr=Eje0*[1*sc 0 0]';
            Eje0yr=Eje0*[0 1*sc 0]';
            Eje0zr=Eje0*[0 0 1*sc]';
    
            Eje1xr=Eje1(1:3,1:3)*[1*sc 0 0]';
            Eje1yr=Eje1(1:3,1:3)*[0 1*sc 0]';
            Eje1zr=Eje1(1:3,1:3)*[0 0 1*sc]';
            Eje2xr=Eje2(1:3,1:3)*[1*sc 0 0]';
            Eje2yr=Eje2(1:3,1:3)*[0 1*sc 0]';
            Eje2zr=Eje2(1:3,1:3)*[0 0 1*sc]';
            Eje3xr=Eje3(1:3,1:3)*[1*sc 0 0]';
            Eje3yr=Eje3(1:3,1:3)*[0 1*sc 0]';
            Eje3zr=Eje3(1:3,1:3)*[0 0 1*sc]';
    
            quiver3(0,0,0, Eje0xr(1), Eje0xr(2), Eje0xr(3), 'r', 'LineWidth', 2);
            quiver3(0,0,0, Eje0yr(1), Eje0yr(2), Eje0yr(3), 'g', 'LineWidth', 2);
            quiver3(0,0,0, 0,0,1*sc, 'y', 'LineWidth', 2);
    
            quiver3(Eje1(1, 4), Eje1(2, 4), Eje1(3, 4), Eje1xr(1), Eje1xr(2), Eje1xr(3), 'r', 'LineWidth', 2); % Eje X
            quiver3(Eje1(1, 4), Eje1(2, 4), Eje1(3, 4), Eje1yr(1), Eje1yr(2), Eje1yr(3), 'g', 'LineWidth', 2); % Eje Y
            quiver3(Eje1(1, 4), Eje1(2, 4), Eje1(3, 4), Eje1zr(1), Eje1zr(2), Eje1zr(3), 'y', 'LineWidth', 2); % Eje Z
    
            quiver3(Eje2(1, 4), Eje2(2, 4), Eje2(3, 4), Eje2xr(1), Eje2xr(2), Eje2xr(3), 'r', 'LineWidth', 2);
            quiver3(Eje2(1, 4), Eje2(2, 4), Eje2(3, 4), Eje2yr(1), Eje2yr(2), Eje2yr(3), 'g', 'LineWidth', 2);
            quiver3(Eje2(1, 4), Eje2(2, 4), Eje2(3, 4), Eje2zr(1), Eje2zr(2), Eje2zr(3), 'y', 'LineWidth', 2);
    
            quiver3(Eje3(1, 4), Eje3(2, 4), Eje3(3, 4), Eje3xr(1), Eje3xr(2), Eje3xr(3), 'r', 'LineWidth', 2);
            quiver3(Eje3(1, 4), Eje3(2, 4), Eje3(3, 4), Eje3yr(1), Eje3yr(2), Eje3yr(3), 'g', 'LineWidth', 2);
            quiver3(Eje3(1, 4), Eje3(2, 4), Eje3(3, 4), Eje3zr(1), Eje3zr(2), Eje3zr(3), 'y', 'LineWidth', 2);
            hold on;

        end
        
        function HOME(app,x,y,z)
            RUTACIRCULAR(app,x,y,z,7,7,7.5)
        end
        
        function sal = ENVIO(app)
            app.q11 = fscanf(app.s,'%d\n');
            app.q22 = fscanf(app.s,'%d\n');
            app.q33 = fscanf(app.s,'%d\n');
            app.q55 = fscanf(app.s,'%d\n');
            app.q66 = fscanf(app.s,'%d\n');
            app.q77 = fscanf(app.s,'%d\n');
            app.q88 = fscanf(app.s,'%d\n');
            app.q44 = fscanf(app.s,'%d\n');

            cadena = ['Q1 = ', num2str(app.q11), ', Q2 = ', num2str(app.q22), ', Q3 = ', num2str(app.q33), ', Q4 = ', num2str(app.q55)];
            disp(cadena);
            sal=app.q44;
        end

        function TRAYECTORIACIRCULAR(app,P1X,P1Y,P1Z,P2X,P2Y,P2Z,C,G)

            cla(app.Posicion)
            cla(app.Velocidad)
            cla(app.Aceleracion)

            l1=92.5;
            l2=171;
            l3=236.5;

            %Trayectoria 1 -Perfil Trapezoidal
            t0 = 0;
            tf = 10;
            t = linspace(t0,tf,15);

            %PUNTO 1
            P1x = P1X;
            P1y = P1Y;
            P1z = P1Z;

            [theta1_P1,theta2_P1,theta3_P1,theta4_P1] = InverseKinematics3R(app,l1,l2,l3,P1x,P1y,P1z,C,G);

            %PUNTO 2
            P2x = P2X;
            P2y = P2Y;
            P2z = P2Z;

            [theta1_P2,theta2_P2,theta3_P2,theta4_P2] = InverseKinematics3R(app,l1,l2,l3,P2x,P2y,P2z,C,G);

            [q1T , dq1T , d2q1T] = lspb(theta1_P1,theta1_P2,t);
            [q2T , dq2T , d2q2T] = lspb(theta2_P1,theta2_P2,t);
            [q3T , dq3T , d2q3T] = lspb(theta3_P1,theta3_P2,t);
            [q4T , ~ , ~] = lspb(theta4_P1,theta4_P2,t);

            for i=1:length(q1T)
                [MTH] = ForwardKinematics3R(app,l1,l2,l3,q1T(i),q2T(i),q3T(i));
                d(:,i) = MTH.t;

                q1=rad2deg(q1T(i));
                q2=rad2deg(q2T(i));
                q3=-rad2deg(q3T(i));
                q4=rad2deg(q4T(i));

                xTCP = num2str(d(1,i));
                yTCP = num2str(d(2,i));
                zTCP = num2str(d(3,i));

                trama=[num2str(q1) ',' num2str(q2) ',' num2str(q3) ',' num2str(q4) ',' xTCP ',' yTCP ',' zTCP];
                fprintf(app.s,'%s\n',trama,'sync');
                while true
                    sala = ENVIO(app);
                    if sala==1
                        sala=0;
                        break;
                    end
                end

                app.Xpos.Value = xTCP;
                app.Ypos.Value = yTCP;
                app.Zpos.Value = zTCP;

                app.Theta1num.Value = num2str(rad2deg(q1T(i)));
                app.Theta2num.Value = num2str(rad2deg(q2T(i)));
                app.Theta3num.Value = num2str(rad2deg(q3T(i)));
                app.Xpos_4.Value = num2str(rad2deg(q4T(i)));
                
                hold on
                plot3(MTH.t(1),MTH.t(2),MTH.t(3),'.b')

                t_(i,1) = t(i);

                q1T_(i,1) = rad2deg(q1T(i));   plot(app.Posicion,t_(:),q1T_(:,1),'-b');  hold(app.Posicion, 'on'); grid(app.Posicion, 'on');
                q2T_(i,1) = rad2deg(q2T(i));   plot(app.Posicion,t_(:),q2T_(:,1),'-g');
                q3T_(i,1) = rad2deg(q3T(i));   plot(app.Posicion,t_(:),q3T_(:,1),'-r');
                title(app.Posicion, 'Posición');

                %Grafica de velocidad
                dq1T_(i,1) = dq1T(i);          plot(app.Velocidad,t_(:),dq1T_(:,1),'-b'); hold(app.Velocidad, 'on'); grid(app.Velocidad, 'on');
                dq2T_(i,1) = dq2T(i);          plot(app.Velocidad,t_(:),dq2T_(:,1),'-g')
                dq3T_(i,1) = dq3T(i);          plot(app.Velocidad,t_(:),dq3T_(:,1),'-r')
                title(app.Velocidad, 'Velocidad');

                %Grafica de aceleración
                d2q1T_(i,1) = d2q1T(i);       plot(app.Aceleracion,t_(:),d2q1T_(:,1),'-b'); hold(app.Aceleracion, 'on'); grid(app.Aceleracion, 'on');
                d2q2T_(i,1) = d2q2T(i);       plot(app.Aceleracion,t_(:),d2q2T_(:,1),'-g')
                d2q3T_(i,1) = d2q3T(i);       plot(app.Aceleracion,t_(:),d2q3T_(:,1),'-r')
                title(app.Aceleracion, 'Aceleracion');
            end
        end
        
        function TRAYECTORIALINEAL(app,P1X,P1Y,P1Z,P2X,P2Y,P2Z,C)

            cla(app.Posicion)
            cla(app.Velocidad)
            cla(app.Aceleracion)

            l1=92.5;
            l2=171;
            l3=236.5;

            %Trayectoria 1 -Perfil Trapezoidal
            t0 = 0;
            tf = 10;
            t = linspace(t0,tf,15);
            %PUNTO 1
            P1x = P1X;
            P1y = P1Y;
            P1z = P1Z;

            %PUNTO 2
            P2x = P2X;
            P2y = P2Y;
            P2z = P2Z;

            [P1T , dP1T , d2P1T] = lspb(P1x,P2x,t);
            [P2T , dP2T , d2P2T] = lspb(P1y,P2y,t);
            [P3T , dP3T , d2P3T] = lspb(P1z,P2z,t);

            for i=1:length(P1T)
                [Pq1T(i,1),Pq2T(i,1),Pq3T(i,1)] = InverseKinematics3R(app,l1,l2,l3,P1T(i),P2T(i),P3T(i),C);
            end

            for i=1:length(Pq1T)
                [MTH] = ForwardKinematics3R(app,l1,l2,l3,Pq1T(i),Pq2T(i),Pq3T(i));

                q1=rad2deg(Pq1T(i));
                q2=rad2deg(Pq2T(i));
                q3=rad2deg(Pq3T(i));

                trama=[num2str(q1) ',' num2str(q2) ',' num2str(q3)];
                fprintf(app.s,'%s\n',trama,'sync');
                while true
                    sala = ENVIO(app);
                    if sala==1
                        sala=0;
                        break;
                    end
                end

                app.Xpos.Value = num2str(P1T(i));
                app.Ypos.Value = num2str(P2T(i));
                app.Zpos.Value = num2str(P3T(i));

                app.Theta1num.Value = num2str(rad2deg(Pq1T(i)));
                app.Theta2num.Value = num2str(rad2deg(Pq2T(i)));
                app.Theta3num.Value = num2str(rad2deg(Pq3T(i)));

                hold on
                plot3(MTH.t(1),MTH.t(2),MTH.t(3),'.r')

                t_(i,1) = t(i);

                q1T_(i,1) = rad2deg(P1T(i));   plot(app.Posicion,t_(:),q1T_(:,1),'-b');  hold(app.Posicion, 'on'); grid(app.Posicion, 'on');
                q2T_(i,1) = rad2deg(P2T(i));   plot(app.Posicion,t_(:),q2T_(:,1),'-g');
                q3T_(i,1) = rad2deg(P3T(i));   plot(app.Posicion,t_(:),q3T_(:,1),'-r');
                title(app.Posicion, 'Posición');

                %Grafica de velocidad
                dq1T_(i,1) = dP1T(i);          plot(app.Velocidad,t_(:),dq1T_(:,1),'-b'); hold(app.Velocidad, 'on'); grid(app.Velocidad, 'on');
                dq2T_(i,1) = dP2T(i);          plot(app.Velocidad,t_(:),dq2T_(:,1),'-g')
                dq3T_(i,1) = dP3T(i);          plot(app.Velocidad,t_(:),dq3T_(:,1),'-r')
                title(app.Velocidad, 'Velocidad');

                %Grafica de aceleración
                d2q1T_(i,1) = d2P1T(i);       plot(app.Aceleracion,t_(:),d2q1T_(:,1),'-b'); hold(app.Aceleracion, 'on'); grid(app.Aceleracion, 'on');
                d2q2T_(i,1) = d2P2T(i);       plot(app.Aceleracion,t_(:),d2q2T_(:,1),'-g')
                d2q3T_(i,1) = d2P3T(i);       plot(app.Aceleracion,t_(:),d2q3T_(:,1),'-r')
                title(app.Aceleracion, 'Aceleracion');
            end
        end
        
        function [eje1,eje2,eje3] = RPEjes(~,q0,q1,q2,l0,l1,l2)
            
            H1=[cos(q0) 0   sin(q0) 0;
                sin(q0) 0   -cos(q0) 0;
                0   1   0   l0;
                0   0   0   1;];
            H2=[cos(q1) -sin(q1) 0 l1*cos(q1);
                sin(q1) cos(q1)  0 l1*sin(q1);
                0   0   1   0;
                0   0   0   1;];
            H3=[cos(q2) -sin(q2)  0 l2*cos(q2);
                sin(q2) cos(q2)   0 l2*sin(q2);
                0   0   1   0;
                0   0   0   1;];
            eje1=H1;
            eje2=H1*H2;
            eje3=H1*H2*H3;

        end

        function [R] = Rotacion(~,angleX,angleY,angleZ)

            % Crear una matriz de transformación para la rotación
            R = makehgtform('zrotate', angleZ, 'yrotate', angleY, 'xrotate', angleX);
            R=R(1:3,1:3)';
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function guidemigrate_OpeningFcn(app, varargin)
            % --- Executes just before guidemigrate is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.TA3, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to guidemigrate (see VARARGIN)
            
            % Choose default command line output for guidemigrate
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);

            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0;
            bottom = screenHeight*0.1;
            width = screenWidth*0.95;
            height = screenHeight*0.85;
            drawnow;
            app.TA3.Position = [left bottom width height];

            app.s=serialport('COM4',9600);
        end

        % Button pushed function: INVERSAButton
        function INVERSAButtonPushed(app, event)
            cla(app.DIBU)

            Px1 = app.Xpos_2.Value;
            Py1 = app.Ypos_2.Value;
            Pz1 = app.Zpos_2.Value;
            Px2 = app.Xpos_3.Value;
            Py2 = app.Ypos_3.Value;
            Pz2 = app.Zpos_3.Value;

            % Px1 = 0;
            % Py1 = 0;
            % Pz1 = 500;
            % Px2 = 0;
            % Py2 = 200;
            % Pz2 = 300;

            if app.CodoAbajoCheckBox.Value == 1
                codo = 1;
            else
                codo = 0;
            end

            TRAYECTORIACIRCULAR(app,Px1,Py1,Pz1,Px2,Py2,Pz2,codo,0);

        end

        % Button pushed function: RECOGERLIBROSButton
        function RECOGERLIBROSButtonPushed(app, event)
            if app.CodoAbajoCheckBox.Value == 1
                codo = 1;
            else
                codo = 0;
            end
            
            % Caja 1
            C1x = 250;
            C1y = 0;
            C1z = 220;
            % Caja 2
            C2x = 250;
            C2y = 20;
            C2z = 220;
            % Caja 3
            C3x = 250;
            C3y = 40;
            C3z = 220;
            % Contenedor 1
            Co1x = 60;
            Co1y = 200;
            Co1z = 200;
            % Contenedor 2
            Co2x = -80;
            Co2y = 200;
            Co2z = 200;
            % Contenedor 3
            Co3x = -200;
            Co3y = 180;
            Co3z = 200;

            % Primera Caja a Contenedor 1
            TRAYECTORIACIRCULAR(app,C1x,C1y,C1z,C1x+50,C1y,C1z,codo,1);
            TRAYECTORIACIRCULAR(app,C1x+50,C1y,C1z,Co1x,Co1y,Co1z,codo,0);
            TRAYECTORIACIRCULAR(app,C1x+50,C1y,C1z,Co1x,Co1y,Co1z,codo,0);
            TRAYECTORIACIRCULAR(app,Co1x,Co1y,Co1z,Co1x,Co1y,Co1z-180,codo,0);
            TRAYECTORIACIRCULAR(app,Co1x,Co1y,Co1z-180,Co1x,Co1y,Co1z-180,codo,1);
            TRAYECTORIACIRCULAR(app,Co1x,Co1y,Co1z-180,Co1x,Co1y,Co1z,codo,1);
            %TRAYECTORIACIRCULAR(app,Co1x,Co1y,Co1z,407.5,0,92.5,codo,1);
            % Segunda Caja a Contenedor 2
            TRAYECTORIACIRCULAR(app,Co1x,Co1y,Co1z,C2x+50,C2y,C2z,codo,1);
            TRAYECTORIACIRCULAR(app,C2x+50,C2y,C2z,C2x,C2y,C2z,codo,0);
            TRAYECTORIACIRCULAR(app,C2x,C2y,C2z,Co2x,Co2y,Co2z,codo,0);
            TRAYECTORIACIRCULAR(app,Co2x,Co2y,Co2z,Co2x,Co2y,Co2z-180,codo,0);
            TRAYECTORIACIRCULAR(app,Co2x,Co2y,Co2z-180,Co2x,Co2y,Co2z-180,codo,1);
            TRAYECTORIACIRCULAR(app,Co2x,Co2y,Co2z-180,Co2x,Co2y,Co2z,codo,1);
            %TRAYECTORIACIRCULAR(app,Co2x,Co2y,Co2z,407.5,0,92.5,codo,1);
            % Tercera Caja a Contenedor 3
            TRAYECTORIACIRCULAR(app,Co2x,Co2y,Co2z,C3x+50,C3y,C3z,codo,1);
            TRAYECTORIACIRCULAR(app,C3x+50,C3y,C3z,C3x,C3y,C3z,codo,0);
            TRAYECTORIACIRCULAR(app,C3x,C3y,C3z,Co3x,Co3y,Co3z,codo,0);
            TRAYECTORIACIRCULAR(app,Co3x,Co3y,Co3z,Co3x,Co3y,Co3z-180,codo,0);
            TRAYECTORIACIRCULAR(app,Co3x,Co3y,Co3z-180,Co3x,Co3y,Co3z-180,codo,1);
            TRAYECTORIACIRCULAR(app,Co3x,Co3y,Co3z-180,Co3x,Co3y,Co3z,codo,1);
            TRAYECTORIACIRCULAR(app,Co3x,Co3y,Co3z,407.5,0,92.5,codo,1);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TA3 = uifigure('Visible', 'off');
            app.TA3.Name = 'guidemigrate';
            app.TA3.Tag = 'figure1';
            app.DIBU = uiaxes(app.TA3);
            app.DIBU.Tag = 'axes1';
            app.Posicion = uiaxes(app.TA3);
            title(app.Posicion, 'Posición')
            xlabel(app.Posicion, 'X')
            ylabel(app.Posicion, 'Y')
            zlabel(app.Posicion, 'Z')
            app.Velocidad = uiaxes(app.TA3);
            title(app.Velocidad, 'Velocidad')
            xlabel(app.Velocidad, 'X')
            ylabel(app.Velocidad, 'Y')
            zlabel(app.Velocidad, 'Z')
            app.Aceleracion = uiaxes(app.TA3);
            title(app.Aceleracion, 'Aceleración')
            xlabel(app.Aceleracion, 'X')
            ylabel(app.Aceleracion, 'Y')
            zlabel(app.Aceleracion, 'Z')
            app.DIBU_2 = uiaxes(app.TA3);
            app.DIBU_2.Tag = 'axes1';
            app.PosStartLabel = uilabel(app.TA3);
            app.PosStartLabel.Text = 'Pos Start';
            app.AngleLabel = uilabel(app.TA3);
            app.AngleLabel.Text = 'Angle';
            app.cmLabel = uilabel(app.TA3);
            app.cmLabel.Text = 'cm';
            app.cmLabel_2 = uilabel(app.TA3);
            app.cmLabel_2.Text = 'cm';
            app.cmLabel_3 = uilabel(app.TA3);
            app.cmLabel_3.Text = 'cm';
            app.Theta3num = uieditfield(app.TA3, 'text');
            app.Theta3num.Editable = 'off';
            app.Theta2EditFieldLabel_2 = uilabel(app.TA3);
            app.Theta2EditFieldLabel_2.Text = {'Theta'; '3'};
            app.XEditFieldLabel = uilabel(app.TA3);
            app.XEditFieldLabel.Text = 'X';
            app.Xpos = uieditfield(app.TA3, 'text');
            app.Xpos.Editable = 'off';
            app.YEditFieldLabel = uilabel(app.TA3);
            app.YEditFieldLabel.Text = 'Y';
            app.Label_2 = uilabel(app.TA3);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = '°';
            app.Label = uilabel(app.TA3);
            app.Label.Visible = 'off';
            app.Label.Text = '°';
            app.Ypos = uieditfield(app.TA3, 'text');
            app.Ypos.Editable = 'off';
            app.ZEditFieldLabel = uilabel(app.TA3);
            app.ZEditFieldLabel.Text = 'Z';
            app.Label_3 = uilabel(app.TA3);
            app.Label_3.Visible = 'off';
            app.Label_3.Text = '°';
            app.Zpos = uieditfield(app.TA3, 'text');
            app.Zpos.Editable = 'off';
            app.Theta1EditFieldLabel = uilabel(app.TA3);
            app.Theta1EditFieldLabel.Text = {'Theta'; '1'};
            app.Theta1num = uieditfield(app.TA3, 'text');
            app.Theta1num.Editable = 'off';
            app.Theta2EditFieldLabel = uilabel(app.TA3);
            app.Theta2EditFieldLabel.Text = {'Theta'; '2'};
            app.Theta2num = uieditfield(app.TA3, 'text');
            app.Theta2num.Editable = 'off';
            app.PosEndLabel = uilabel(app.TA3);
            app.PosEndLabel.Text = 'Pos End';
            app.cmLabel_4 = uilabel(app.TA3);
            app.cmLabel_4.Text = 'cm';
            app.cmLabel_5 = uilabel(app.TA3);
            app.cmLabel_5.Text = 'cm';
            app.cmLabel_6 = uilabel(app.TA3);
            app.cmLabel_6.Text = 'cm';
            app.INVERSAButton = uibutton(app.TA3, 'push');
            app.INVERSAButton.ButtonPushedFcn = createCallbackFcn(app, @INVERSAButtonPushed, true);
            app.INVERSAButton.Text = 'INVERSA';
            app.cmLabel_7 = uilabel(app.TA3);
            app.cmLabel_7.Text = 'cm';
            app.cmLabel_8 = uilabel(app.TA3);
            app.cmLabel_8.Text = 'cm';
            app.cmLabel_9 = uilabel(app.TA3);
            app.cmLabel_9.Text = 'cm';
            app.ActualPosLabel = uilabel(app.TA3);
            app.ActualPosLabel.Text = 'Actual Pos';
            app.XEditFieldLabel_3 = uilabel(app.TA3);
            app.XEditFieldLabel_3.Text = 'X';
            app.Xpos_3 = uieditfield(app.TA3, 'numeric');
            app.YEditFieldLabel_2 = uilabel(app.TA3);
            app.YEditFieldLabel_2.Text = 'Y';
            app.Ypos_2 = uieditfield(app.TA3, 'numeric');
            app.YEditField_2Label = uilabel(app.TA3);
            app.YEditField_2Label.Text = 'Y';
            app.Ypos_3 = uieditfield(app.TA3, 'numeric');
            app.XEditFieldLabel_2 = uilabel(app.TA3);
            app.XEditFieldLabel_2.Text = 'X';
            app.ZEditField_2Label = uilabel(app.TA3);
            app.ZEditField_2Label.Text = 'Z';
            app.Zpos_3 = uieditfield(app.TA3, 'numeric');
            app.CodoArribaCheckBox = uicheckbox(app.TA3);
            app.CodoArribaCheckBox.Text = 'Codo Arriba';
            app.CodoAbajoCheckBox = uicheckbox(app.TA3);
            app.CodoAbajoCheckBox.Text = 'Codo Abajo';
            app.MTH01 = uitextarea(app.TA3);
            app.MTH01.Visible = 'off';
            app.MTH12 = uitextarea(app.TA3);
            app.MTH12.Visible = 'off';
            app.MTH23 = uitextarea(app.TA3);
            app.MTH23.Visible = 'off';
            app.MTH03 = uitextarea(app.TA3);
            app.MTH01Label = uilabel(app.TA3);
            app.MTH01Label.Visible = 'off';
            app.MTH01Label.Text = 'MTH 0 -1';
            app.MTH12Label = uilabel(app.TA3);
            app.MTH12Label.Visible = 'off';
            app.MTH12Label.Text = 'MTH 1 - 2';
            app.MTH23Label = uilabel(app.TA3);
            app.MTH23Label.Visible = 'off';
            app.MTH23Label.Text = 'MTH 2 - 3';
            app.MTH03Label = uilabel(app.TA3);
            app.MTH03Label.Text = 'MTH 0 - 3';
            app.RollEditFieldLabel = uilabel(app.TA3);
            app.RollEditFieldLabel.Visible = 'off';
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.TA3, 'numeric');
            app.RollEditField.Visible = 'off';
            app.PitchEditFieldLabel = uilabel(app.TA3);
            app.PitchEditFieldLabel.Visible = 'off';
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.TA3, 'numeric');
            app.PitchEditField.Visible = 'off';
            app.YawEditFieldLabel = uilabel(app.TA3);
            app.YawEditFieldLabel.Visible = 'off';
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.TA3, 'numeric');
            app.YawEditField.Visible = 'off';
            app.RollEditField_2Label = uilabel(app.TA3);
            app.RollEditField_2Label.Visible = 'off';
            app.RollEditField_2Label.Text = 'Roll';
            app.RollEditField_2 = uieditfield(app.TA3, 'numeric');
            app.RollEditField_2.Visible = 'off';
            app.PitchEditField_2Label = uilabel(app.TA3);
            app.PitchEditField_2Label.Visible = 'off';
            app.PitchEditField_2Label.Text = 'Pitch';
            app.PitchEditField_2 = uieditfield(app.TA3, 'numeric');
            app.PitchEditField_2.Visible = 'off';
            app.YawEditField_2Label = uilabel(app.TA3);
            app.YawEditField_2Label.Visible = 'off';
            app.YawEditField_2Label.Text = 'Yaw';
            app.YawEditField_2 = uieditfield(app.TA3, 'numeric');
            app.YawEditField_2.Visible = 'off';
            app.RollEditField_3Label = uilabel(app.TA3);
            app.RollEditField_3Label.Visible = 'off';
            app.RollEditField_3Label.Text = 'Roll';
            app.RollEditField_3 = uieditfield(app.TA3, 'numeric');
            app.RollEditField_3.Visible = 'off';
            app.PitchEditField_3Label = uilabel(app.TA3);
            app.PitchEditField_3Label.Visible = 'off';
            app.PitchEditField_3Label.Text = 'Pitch';
            app.PitchEditField_3 = uieditfield(app.TA3, 'numeric');
            app.PitchEditField_3.Visible = 'off';
            app.YawEditField_3Label = uilabel(app.TA3);
            app.YawEditField_3Label.Visible = 'off';
            app.YawEditField_3Label.Text = 'Yaw';
            app.YawEditField_3 = uieditfield(app.TA3, 'numeric');
            app.YawEditField_3.Visible = 'off';
            app.RollEditField_4Label = uilabel(app.TA3);
            app.RollEditField_4Label.Text = 'Roll';
            app.RollEditField_4 = uieditfield(app.TA3, 'numeric');
            app.PitchEditField_4Label = uilabel(app.TA3);
            app.PitchEditField_4Label.Text = 'Pitch';
            app.PitchEditField_4 = uieditfield(app.TA3, 'numeric');
            app.YawEditField_4Label = uilabel(app.TA3);
            app.YawEditField_4Label.Text = 'Yaw';
            app.YawEditField_4 = uieditfield(app.TA3, 'numeric');
            app.RECOGERLIBROSButton = uibutton(app.TA3, 'push');
            app.RECOGERLIBROSButton.ButtonPushedFcn = createCallbackFcn(app, @RECOGERLIBROSButtonPushed, true);
            app.RECOGERLIBROSButton.Text = 'RECOGER LIBROS';
            app.GripperLabel = uilabel(app.TA3);
            app.GripperLabel.Text = 'Gripper';
            app.Xpos_4 = uieditfield(app.TA3, 'text');
            app.Xpos_4.Editable = 'off';
            app.Label_4 = uilabel(app.TA3);
            app.Label_4.Visible = 'off';
            app.Label_4.Text = '°';
            app.Xpos_2 = uieditfield(app.TA3, 'numeric');
            app.ZEditFieldLabel_2 = uilabel(app.TA3);
            app.ZEditFieldLabel_2.Text = 'Z';
            app.Zpos_2 = uieditfield(app.TA3, 'numeric');
            % Show the figure after all components are created
            app.TA3.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LAB_Inversa(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TA3)

                % Execute the startup function
                runStartupFcn(app, @(app)guidemigrate_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.TA3)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TA3)
        end
    end
end